classdef happOrSad < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Label                        matlab.ui.control.Label
        getimageButton               matlab.ui.control.Button
        EditField                    matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        Above60HappyBelow60SadLabel  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("C:\Users\My Self\Downloads\images\","-begin","-frozen");
        end

        % Button pushed function: getimageButton
        function getimageButtonPushed(app, event)
           global value ;
           value = app.EditField.Value;
            
            if value > 60
                app.Image.ImageSource = 'images/happy.jpeg';
                app.Label.Text = "Happy :)";
                app.Label.FontColor = "green";
            elseif value < 60
                app.Image.ImageSource = 'images/sad.jpeg';
                app.Label.Text = "Sad :(";
                app.Label.FontColor = "red";
            end
        
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            global value;
            value = app.EditField.Value;
            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'confused.jpg';
            app.Above60HappyBelow60SadLabel = uilabel(app.UIFigure);
            app.Above60HappyBelow60SadLabel.Text = {'Above 60  => Happy :)'; 'Below 60  => Sad     :('};
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [0 100];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.getimageButton = uibutton(app.UIFigure, 'push');
            app.getimageButton.ButtonPushedFcn = createCallbackFcn(app, @getimageButtonPushed, true);
            app.getimageButton.Text = 'get image';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = happOrSad

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end